classdef MallSanitizationGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Store1Label                matlab.ui.control.Label
        Store2Label                matlab.ui.control.Label
        Store3Label                matlab.ui.control.Label
        Store4Label                matlab.ui.control.Label
        Store5Label                matlab.ui.control.Label
        Image_11                   matlab.ui.control.Image
        Image_10                   matlab.ui.control.Image
        Image_9                    matlab.ui.control.Image
        Image_8                    matlab.ui.control.Image
        Image_6                    matlab.ui.control.Image
        UITable                    matlab.ui.control.Table
        TrackingPanel              matlab.ui.container.Panel
        SanitizationRequestsPanel  matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        Store5CheckBox             matlab.ui.control.CheckBox
        Store4CheckBox             matlab.ui.control.CheckBox
        Store3CheckBox             matlab.ui.control.CheckBox
        Store2CheckBox             matlab.ui.control.CheckBox
        Image_5                    matlab.ui.control.Image
        Image_4                    matlab.ui.control.Image
        Image_3                    matlab.ui.control.Image
        Image_2                    matlab.ui.control.Image
        Store1CheckBox             matlab.ui.control.CheckBox
        UIAxes                     matlab.ui.control.UIAxes
    end

 
    properties (Access = private)
        st_locs = [1 0.55; 2.3 0.54; 2.65 -0.13; 2.2 -0.9; 0.8 -0.9];
        st_dists = [0, 2, 4, 2.2361, 3.6056; 2, 0, 2, 2.2361, 2.2361; 4, 2, 0, 3.6056, 2.2361; 2.2361, 2.2361, 3.6056, 0, 2; 3.6056, 2.2361, 2.2361, 2, 0];
        st_sel = [0, 0, 0, 0, 0];
        
        %rob_start_locs = [0.25, -0.25; 0.25, 0.25];
        rob_start_angs = [pi/2, pi/2];
        rob_curr_locs
        rob_curr_angs
        rob_speed = 1; %%%%%%%%%%
        
        request_window = 15;
        terminate = 0;
        tracking_info = [{'A'}, {'(none'}, {'-'}; {'B'}, {'(none)'} ,{'-'}];
        
        robA_st_nums = []
        robB_st_nums = []

        our_IP = '192.168.1.7'
        robotA_pos_sub
        robotB_pos_sub
        robotA_or_sub
        robotB_or_sub
        robotA_status_sub
        robotB_status_sub
        robotA_goals_msg
        robotA_goals_pub
        robotB_goals_msg
        robotB_goals_pub
    end
    
    methods (Access = private)
        
        function operate(app)
            
            while(~app.terminate)
                while (sum(app.st_sel,'all') == 0)
                    pause(app.request_window);
                end
                app.SanitizationRequestsPanel.Enable = "off";

                findBestPaths(app)

                % actual execution in an outside function that opens threads
                % executeNavSanit(app.robA_st_nums, app.robB_st_nums);
                app.robotA_goals_msg.Data = int32(app.robA_st_nums);
                send(app.robotA_goals_pub, app.robotA_goals_msg);

                app.robotB_goals_msg.Data = int32(app.robB_st_nums);
                send(app.robotB_goals_pub, app.robotB_goals_msg);

                [robA_status] = receive(app.robotA_status_sub);
                robA_status = double(robA_status.Data)
                [robB_status] = receive(app.robotB_status_sub);
                robB_status = double(robB_status.Data)
                done_executing = (robA_status == 0) && (robB_status == 0)
                %done_executing = (robA_status == 0)
                while (~done_executing)
                    here1 = 1
                    updateTrackingPanel(app);
                    here2 = 1
                    plotRobots(app);
                    here3 = 1
                    
                    [robA_status] = receive(app.robotA_status_sub);
                    robA_status = double(robA_status.Data)
                    [robB_status] = receive(app.robotB_status_sub);
                    robB_status = double(robB_status.Data)
                    done_executing = (robA_status == 0) && (robB_status == 0)
                    %done_executing = (robA_status == 0)

                end
                resetTrackingPanel(app);
                resetReqPanel(app);
            end
        end
        
        function plotRobots(app)
            updateRobPosAng(app);
            robotA_pos = app.rob_curr_locs(1,:);
            robotA_angR = app.rob_curr_angs(1);
            robotB_pos = app.rob_curr_locs(2,:);
            robotB_angR = app.rob_curr_angs(2);
            
            cla(app.UIAxes);
            
            plotA_tr_vec = [robotA_pos, 0];
            plotA_rot_vec = axang2quat([0, 0, 1, robotA_angR]);
            plotTransforms(plotA_tr_vec, plotA_rot_vec, "MeshFilePath", "groundvehicle.stl", "Parent", app.UIAxes, "View","2D", "FrameSize", 0.2,'MeshColor','blue');
            text(app.UIAxes, robotA_pos(1)-0.2, robotA_pos(2)-0.2,'Robot A')
            
            plotB_tr_vec = [robotB_pos, 0];
            plotB_rot_vec = axang2quat([0, 0, 1, robotB_angR]);
            plotTransforms(plotB_tr_vec, plotB_rot_vec, "MeshFilePath", "groundvehicle.stl", "Parent", app.UIAxes, "View","2D", "FrameSize", 0.2,'MeshColor','red');
            text(app.UIAxes, robotB_pos(1)-0.2, robotB_pos(2)-0.2,'Robot B')
            close all
        end
        
        function findBestPaths(app)
            st_sel_locs = app.st_locs(find(app.st_sel == 1), :);

            
            [min_dist, best_tour, generation] = mdmtspv_MUTATED_ga(st_sel_locs, 2, app.rob_curr_locs);

            robotA_st = best_tour(1,:);
            robotA_st = robotA_st(robotA_st~=0);
            if (robotA_st)
                robA_st_locs = st_sel_locs(robotA_st, :);
                app.robA_st_nums = stLocsToNums(app, robA_st_locs);
            else
                app.robA_st_nums = [];
            end

            robotB_st = best_tour(2,:);
            robotB_st = robotB_st(robotB_st~=0);
            if (robotB_st)
                robB_st_locs = st_sel_locs(robotB_st, :);
                app.robB_st_nums = stLocsToNums(app, robB_st_locs);
            else
                app.robB_st_nums = [];
            end
        end
        
        function rob_st_nums = stLocsToNums(app, rob_st_locs)
            num_assigned_st = size(rob_st_locs, 1);

            rob_st_nums = zeros(1, num_assigned_st);

            for i = 1:num_assigned_st
                for j = 1:size(app.st_locs, 1)
                    if (sum(rob_st_locs(i, :) == (app.st_locs(j, :))) == 2)
                        rob_st_nums(i) = j;
                        break;
                    end
                end
            end
        end
        
        function updateTrackingPanel(app)
            [robA_status] = receive(app.robotA_status_sub);
            robA_status = double(robA_status.Data);
            
            if (robA_status == 0)
                robA_data =  [{'A'}, {'(none)'}, {'-'}];
            else
                robA_data = [];
                ETA = (norm(app.rob_curr_locs(1, :) - app.st_locs(robA_status, :)))/app.rob_speed;
                robA_data = [robA_data; [{'A'}, {int8(robA_status)}, {ETA}]];
         
                for i = find(app.robA_st_nums == robA_status):length(app.robA_st_nums) - 1
                    ETA = ETA + app.st_dists(app.robA_st_nums(i), app.robA_st_nums(i+1))/app.rob_speed;
                    robA_data = [robA_data; [{'A'}, {int8(app.robA_st_nums(i+1))}, {ETA}]];
                end
            end
 
             [robB_status] = receive(app.robotB_status_sub);
             robB_status = double(robB_status.Data);
             
             if (robB_status == 0)
                 robB_data =  [{'B'}, {'(none)'}, {'-'}];
             else
                 robB_data = [];
                 ETA = (norm(app.rob_curr_locs(2, :) - app.st_locs(robB_status, :)))/app.rob_speed;
                 robB_data = [robB_data; [{'B'}, {int8(robB_status)}, {ETA}]];
          
                 for i = find(app.robB_st_nums == robB_status):length(app.robB_st_nums) - 1
                     ETA = ETA + app.st_dists(app.robB_st_nums(i), app.robB_st_nums(i+1))/app.rob_speed;
                     robB_data = [robB_data; [{'B'}, {int8(app.robB_st_nums(i+1))}, {ETA}]];
                 end
             end

            app.tracking_info = [robA_data; robB_data];
            %app.tracking_info = [robA_data];
            app.UITable.Data = app.tracking_info;            
        end
        
        function resetReqPanel(app)
            app.Store1CheckBox.Value = 0;
            app.Store2CheckBox.Value = 0;
            app.Store3CheckBox.Value = 0;
            app.Store4CheckBox.Value = 0;
            app.Store5CheckBox.Value = 0;
            app.st_sel = [0, 0, 0, 0, 0];
            app.SanitizationRequestsPanel.Enable = "on";
        end
        
        function initRos(app)
            rosshutdown;
            rosinit(app.our_IP);

            app.robotA_pos_sub = rossubscriber("/pos_robA","DataFormat","struct");
            app.robotB_pos_sub = rossubscriber("/pos_robB","DataFormat","struct");

            app.robotA_or_sub = rossubscriber("/qualisys/robotA/pose","DataFormat","struct");
            app.robotB_or_sub = rossubscriber("/qualisys/robotB/pose","DataFormat","struct");

            app.robotA_status_sub = rossubscriber("/Astatus","DataFormat","struct");
            app.robotB_status_sub = rossubscriber("/Bstatus","DataFormat","struct");
            
            app.robotA_goals_pub = rospublisher("/Agoals","std_msgs/Int32MultiArray","DataFormat","struct");
            app.robotA_goals_msg = rosmessage(app.robotA_goals_pub);
            app.robotB_goals_pub = rospublisher("/Bgoals","std_msgs/Int32MultiArray","DataFormat","struct");
            app.robotB_goals_msg = rosmessage(app.robotB_goals_pub);
        end
        
        function updateRobPosAng(app)
            [robA_pos] = receive(app.robotA_pos_sub);
            robA_pos = double(robA_pos.Data)
            app.rob_curr_locs(1, :) = [robA_pos(1) robA_pos(3)];

            [robA_or] = receive(app.robotA_or_sub);
            robA_or_vec = double([robA_or.Pose.Orientation.X robA_or.Pose.Orientation.Z robA_or.Pose.Orientation.Y robA_or.Pose.Orientation.W]);
            robA_or_vec = rad2deg(quat2eul(robA_or_vec,'XYZ')); 
            app.rob_curr_angs(1) = deg2rad(robA_or_vec(1));

            [robB_pos] = receive(app.robotB_pos_sub);
            robB_pos = double(robB_pos.Data)
            app.rob_curr_locs(2, :) = [robB_pos(1) robB_pos(3)];

            [robB_or] = receive(app.robotB_or_sub);
            robB_or_vec = double([robB_or.Pose.Orientation.X robB_or.Pose.Orientation.Z robB_or.Pose.Orientation.Y robB_or.Pose.Orientation.W]);
            robB_or_vec = rad2deg(quat2eul(robB_or_vec,'XYZ')); 
            app.rob_curr_angs(2) = deg2rad(robB_or_vec(1));
        end
        
        function resetTrackingPanel(app)
            robA_data =  [{'A'}, {'(none)'}, {'-'}];
            robB_data =  [{'B'}, {'(none)'}, {'-'}];

            app.tracking_info = [robA_data; robB_data];
            app.UITable.Data = app.tracking_info;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            initRos(app);
            app.UITable.Data = app.tracking_info;
            plotRobots(app);

            operate(app);
        end

        % Value changed function: Store1CheckBox
        function Store1CheckBoxValueChanged(app, event)
            app.st_sel(1) = app.Store1CheckBox.Value;
        end

        % Value changed function: Store2CheckBox
        function Store2CheckBoxValueChanged(app, event)
            app.st_sel(2) = app.Store2CheckBox.Value;

        end

        % Value changed function: Store3CheckBox
        function Store3CheckBoxValueChanged(app, event)
            app.st_sel(3) = app.Store3CheckBox.Value;
        end

        % Value changed function: Store4CheckBox
        function Store4CheckBoxValueChanged(app, event)
            app.st_sel(4) = app.Store4CheckBox.Value;
        end

        % Value changed function: Store5CheckBox
        function Store5CheckBoxValueChanged(app, event)
            app.st_sel(5) = app.Store5CheckBox.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.terminate = 1;
            rosshutdown;
            clear mdmtspv_MUTATED_ga;
            close all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Y')
            ylabel(app.UIAxes, 'X')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 3];
            app.UIAxes.YLim = [-1.2 1.2];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XTick = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2 2.25 2.5 2.75 3];
            app.UIAxes.XTickLabel = {'0'; '0.25'; '0.5'; '0.75'; '1'; '1.25'; '1.5'; '1.75'; '2'; '2.25'; '2.5'; '2.75'; '3'};
            app.UIAxes.YTick = [-1.2 -1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1 1.2];
            app.UIAxes.YTickLabel = {'-1.2'; '-1'; '-0.8'; '-0.6'; '-0.4'; '-0.2'; '0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'; '1.2'};
            app.SanitizationRequestsPanel = uipanel(app.UIFigure);
            app.SanitizationRequestsPanel.Title = 'Sanitization Requests';
            app.Store1CheckBox = uicheckbox(app.SanitizationRequestsPanel);
            app.Store1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Store1CheckBoxValueChanged, true);
            app.Store1CheckBox.Text = 'Store 1';
            app.Image_2 = uiimage(app.SanitizationRequestsPanel);
            app.Image_2.ImageSource = 'Store.PNG';
            app.Image_3 = uiimage(app.SanitizationRequestsPanel);
            app.Image_3.ImageSource = 'Store.PNG';
            app.Image_4 = uiimage(app.SanitizationRequestsPanel);
            app.Image_4.ImageSource = 'Store.PNG';
            app.Image_5 = uiimage(app.SanitizationRequestsPanel);
            app.Image_5.ImageSource = 'Store.PNG';
            app.Store2CheckBox = uicheckbox(app.SanitizationRequestsPanel);
            app.Store2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Store2CheckBoxValueChanged, true);
            app.Store2CheckBox.Text = 'Store 2';
            app.Store3CheckBox = uicheckbox(app.SanitizationRequestsPanel);
            app.Store3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Store3CheckBoxValueChanged, true);
            app.Store3CheckBox.Text = 'Store 3';
            app.Store4CheckBox = uicheckbox(app.SanitizationRequestsPanel);
            app.Store4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Store4CheckBoxValueChanged, true);
            app.Store4CheckBox.Text = 'Store 4';
            app.Store5CheckBox = uicheckbox(app.SanitizationRequestsPanel);
            app.Store5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Store5CheckBoxValueChanged, true);
            app.Store5CheckBox.Text = 'Store 5';
            app.Image = uiimage(app.SanitizationRequestsPanel);
            app.Image.ImageSource = 'Store.PNG';
            app.TrackingPanel = uipanel(app.UIFigure);
            app.TrackingPanel.Title = 'Tracking';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Robot'; 'Assigned Store'; 'ETA'};
            app.UITable.RowName = {};
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageSource = 'Store.PNG';
            app.Image_8 = uiimage(app.UIFigure);
            app.Image_8.ImageSource = 'Store.PNG';
            app.Image_9 = uiimage(app.UIFigure);
            app.Image_9.ImageSource = 'Store.PNG';
            app.Image_10 = uiimage(app.UIFigure);
            app.Image_10.ImageSource = 'Store.PNG';
            app.Image_11 = uiimage(app.UIFigure);
            app.Image_11.ImageSource = 'Store.PNG';
            app.Store5Label = uilabel(app.UIFigure);
            app.Store5Label.Text = 'Store 5';
            app.Store4Label = uilabel(app.UIFigure);
            app.Store4Label.Text = 'Store 4';
            app.Store3Label = uilabel(app.UIFigure);
            app.Store3Label.Text = 'Store 3';
            app.Store2Label = uilabel(app.UIFigure);
            app.Store2Label.Text = 'Store 2';
            app.Store1Label = uilabel(app.UIFigure);
            app.Store1Label.Text = 'Store 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MallSanitizationGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end